classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        drawButton                   matlab.ui.control.Button
        startdrawingwithdobotButton  matlab.ui.control.Button
        resetthedataButton           matlab.ui.control.Button
        plzstarturdrawingLabel       matlab.ui.control.Label
        safteystopButton             matlab.ui.control.Button
        resumeButton                 matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
             ax = axes('Parent', uifigure, 'xlim',([0.1,0.4]),'ylim',([0.1,0.4]));
           F=drawfreehand(ax);
           F.Position
           disp F.Position; 
           filename = 'testdata.mat';
           save(filename)
        end

        % Button pushed function: startdrawingwithdobotButton
        function DobotDraw(app, event)
            %% Load Robot
%% Load Robot
robotbase = eye(4)*transl(0,0.2,-0.41);
axis equal
robot = dorobot('robot',robotbase);
hold on

%% Load environment
environment
%% Get Reach
%robot.plotCloud();
%% get data
load('testdata.mat')
xaxis = ans(:,1);
yaxis = ans(:,2);
%% Movement
%function : move2knowlocation() & move2unknowlocation()
    moving
    movement.move2knowlocation(robot,deg2rad([0,80,-70,0]))

        end

        % Button pushed function: safteystopButton
        function stop(app, event)
            uiwait 
            disp ('emergency stop')
        end

        % Button pushed function: resumeButton
        function resumeButtonPushed(app, event)
            uiresume
            disp ('resume')
        end

        % Button pushed function: resetthedataButton
        function resetthedataButtonPushed(app, event)
            delete("testdata.mat");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.drawButton = uibutton(app.UIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = {'draw'; ''};
            app.startdrawingwithdobotButton = uibutton(app.UIFigure, 'push');
            app.startdrawingwithdobotButton.ButtonPushedFcn = createCallbackFcn(app, @DobotDraw, true);
            app.startdrawingwithdobotButton.Text = 'start drawing with dobot';
            app.resetthedataButton = uibutton(app.UIFigure, 'push');
            app.resetthedataButton.ButtonPushedFcn = createCallbackFcn(app, @resetthedataButtonPushed, true);
            app.resetthedataButton.Text = 'reset the data';
            app.plzstarturdrawingLabel = uilabel(app.UIFigure);
            app.plzstarturdrawingLabel.Text = 'plz start ur drawing';
            app.safteystopButton = uibutton(app.UIFigure, 'push');
            app.safteystopButton.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.safteystopButton.Text = 'saftey stop';
            app.resumeButton = uibutton(app.UIFigure, 'push');
            app.resumeButton.ButtonPushedFcn = createCallbackFcn(app, @resumeButtonPushed, true);
            app.resumeButton.Text = 'resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end